classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotPanel                 matlab.ui.container.Panel
        Image3                    matlab.ui.control.Image
        TextArea                  matlab.ui.control.TextArea
        SlicesButton              matlab.ui.control.Button
        StructureButton           matlab.ui.control.Button
        ModelButton               matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        AdaptiveSlicingTab        matlab.ui.container.Tab
        SettingsPanel             matlab.ui.container.Panel
        STLfileDropDownLabel      matlab.ui.control.Label
        STLfileDropDown           matlab.ui.control.DropDown
        IncrementsEditFieldLabel  matlab.ui.control.Label
        IncrementsEditField       matlab.ui.control.NumericEditField
        CustomFileButton          matlab.ui.control.Button
        SliceButton               matlab.ui.control.Button
        AdaptiveSlicingPanel      matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        CurvedLayerSlicingTab     matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Property % Description
    % Variables for GUI
    img
    file = "Cube"
    increments = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('AdaptiveParameters.xlsx');
            app.UITable.Data = t;
            app.UITable.ColumnEditable = true;
        end

        % Button pushed function: CustomFileButton
        function CustomFileButtonPushed(app, event)
            app.file = uigetfile;
        end

        % Button pushed function: SliceButton
        function SliceButtonPushed(app, event)
            Slicing("Models/" + app.file + ".stl", app.increments);
            app.TextArea.Value = "";
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("Slicing...")]; 
            app.TextArea.Value = [string(app.TextArea.Value); sprintf("STL file: %s" ,(app.file))]; 
        end

        % Button pushed function: SlicesButton
        function SlicesButtonPushed(app, event)
            app.img = imread("slicing.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Button pushed function: StructureButton
        function StructureButtonPushed(app, event)
            app.img = imread("structure.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
            app.img = imread("model.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Value changed function: STLfileDropDown
        function STLfileDropDownValueChanged(app, event)
            value = app.STLfileDropDown.Value;
            app.file = value;
        end

        % Value changed function: IncrementsEditField
        function IncrementsEditFieldValueChanged(app, event)
            value = app.IncrementsEditField.Value;
            app.increments = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.Image3 = uiimage(app.PlotPanel);
            app.Image3.ImageSource = 'default.jpg';
            app.TextArea = uitextarea(app.PlotPanel);
            app.SlicesButton = uibutton(app.PlotPanel, 'push');
            app.SlicesButton.ButtonPushedFcn = createCallbackFcn(app, @SlicesButtonPushed, true);
            app.SlicesButton.Text = 'Slices';
            app.StructureButton = uibutton(app.PlotPanel, 'push');
            app.StructureButton.ButtonPushedFcn = createCallbackFcn(app, @StructureButtonPushed, true);
            app.StructureButton.Text = 'Structure';
            app.ModelButton = uibutton(app.PlotPanel, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Text = 'Model';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdaptiveSlicingTab = uitab(app.TabGroup);
            app.AdaptiveSlicingTab.Title = 'Adaptive Slicing';
            app.SettingsPanel = uipanel(app.AdaptiveSlicingTab);
            app.SettingsPanel.Title = 'Settings';
            app.STLfileDropDownLabel = uilabel(app.SettingsPanel);
            app.STLfileDropDownLabel.Text = {'STL file'; ''};
            app.STLfileDropDown = uidropdown(app.SettingsPanel);
            app.STLfileDropDown.Items = {'Cube', 'Shape1', 'Shape2', 'Shape3', 'Shape4', 'Shape5', 'Pikachu'};
            app.STLfileDropDown.ValueChangedFcn = createCallbackFcn(app, @STLfileDropDownValueChanged, true);
            app.STLfileDropDown.Value = 'Cube';
            app.IncrementsEditFieldLabel = uilabel(app.SettingsPanel);
            app.IncrementsEditFieldLabel.Text = 'Increments';
            app.IncrementsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.IncrementsEditField.ValueChangedFcn = createCallbackFcn(app, @IncrementsEditFieldValueChanged, true);
            app.IncrementsEditField.Value = 1;
            app.CustomFileButton = uibutton(app.SettingsPanel, 'push');
            app.CustomFileButton.ButtonPushedFcn = createCallbackFcn(app, @CustomFileButtonPushed, true);
            app.CustomFileButton.Text = 'Custom File';
            app.SliceButton = uibutton(app.SettingsPanel, 'push');
            app.SliceButton.ButtonPushedFcn = createCallbackFcn(app, @SliceButtonPushed, true);
            app.SliceButton.Text = 'Slice';
            app.AdaptiveSlicingPanel = uipanel(app.AdaptiveSlicingTab);
            app.AdaptiveSlicingPanel.Title = 'Adaptive Slicing';
            app.UITable = uitable(app.AdaptiveSlicingTab);
            app.UITable.ColumnName = {'Min Angle'; 'Max Angle'; 'Min Residue'; 'Max Residue'; 'Thickness'};
            app.UITable.RowName = {};
            app.CurvedLayerSlicingTab = uitab(app.TabGroup);
            app.CurvedLayerSlicingTab.Title = 'Curved Layer Slicing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end